classdef STRAINS_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        CloseAllButton                matlab.ui.control.Button
        SmoothingwindowsizeEditField  matlab.ui.control.NumericEditField
        SmoothingwindowsizeLabel      matlab.ui.control.Label
        MultiCellAnalysisPanel        matlab.ui.container.Panel
        CellPopulationsButton         matlab.ui.control.Button
        HistogramTimeseriesButton     matlab.ui.control.Button
        HighlightCellButton           matlab.ui.control.Button
        TimepointHistogramsButton     matlab.ui.control.Button
        ClearCurrentDataButton        matlab.ui.control.Button
        SelectAreaButton              matlab.ui.control.Button
        FrameSlider                   matlab.ui.control.Slider
        SingleCellAnalysisPanel       matlab.ui.container.Panel
        ClosestCellyEditField         matlab.ui.control.NumericEditField
        ClosestCellyEditFieldLabel    matlab.ui.control.Label
        ClosestCellxEditField         matlab.ui.control.NumericEditField
        ClosestCellxEditFieldLabel    matlab.ui.control.Label
        CellIDEditField               matlab.ui.control.NumericEditField
        CellIDEditFieldLabel          matlab.ui.control.Label
        SelectcellfromcoordinatesButton  matlab.ui.control.Button
        YCoordinateEditField          matlab.ui.control.NumericEditField
        YCoordinateEditFieldLabel     matlab.ui.control.Label
        XCoordinateEditField          matlab.ui.control.NumericEditField
        XCoordinateEditFieldLabel     matlab.ui.control.Label
        SelectCellFromImageButton     matlab.ui.control.Button
        VideoControlsPanel            matlab.ui.container.Panel
        FramerateEditField            matlab.ui.control.NumericEditField
        FramerateEditFieldLabel       matlab.ui.control.Label
        SelectvideoButton             matlab.ui.control.Button
        PlayPauseButton               matlab.ui.control.StateButton
        SaveCellIDListButton          matlab.ui.control.Button
        FrameLabel                    matlab.ui.control.Label
        ImageAxes                     matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        BlueAxes                      matlab.ui.control.UIAxes
        RedAxes                       matlab.ui.control.UIAxes
        GreenAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % dependencies:
        % requires tracking and intensity codes to be run before using this app
        % only use for visualizing intensity information after analysis
        % requires all images to be in the same folder as their analysis files: '~_locs.mat' and '~_intensity.mat'
        % REQUIRES MATLAB 2019a OR LATER
        % This GUI is designed to run on a monitor. It may not work properly on a small laptop
        % screen. Resizing the image tends to mess with the click detection. Any standard computer
        % monitor should work.
        
        
    % MIT License
        % Copyright 2022 Jingyang Zheng
        % Permission is hereby granted, free of charge, to any person obtaining a copy of this software and associated 
        % documentation files (the "Software"), to deal in the Software without restriction, including without limitation 
        % the rights to use, copy, modify, merge, publish, distribute, sublicense, and/or sell copies of the Software, 
        % and to permit persons to whom the Software is furnished to do so, subject to the following conditions: 
        % The above copyright notice and this permission notice shall be included in all copies or substantial portions of the Software.
        % THE SOFTWARE IS PROVIDED "AS IS", WITHOUT WARRANTY OF ANY KIND, EXPRESS OR IMPLIED, INCLUDING BUT NOT LIMITED TO 
        % THE WARRANTIES OF MERCHANTABILITY, FITNESS FOR A PARTICULAR PURPOSE AND NONINFRINGEMENT. IN NO EVENT SHALL 
        % THE AUTHORS OR COPYRIGHT HOLDERS BE LIABLE FOR ANY CLAIM, DAMAGES OR OTHER LIABILITY, WHETHER IN AN ACTION OF CONTRACT, 
        % TORT OR OTHERWISE, ARISING FROM, OUT OF OR IN CONNECTION WITH THE SOFTWARE OR THE USE OR OTHER DEALINGS IN THE SOFTWARE.
    
    % Citation
        % Attribution to the copyright holder (Jingyang Zheng) and citation of the associated publication 
        % (https://www.biorxiv.org/content/10.1101/2022.06.12.495830v2). The authors would appreciate if any users 
        % could email the copyright holder (jz848@cornell.edu) so that the copyright holder can share and cite examples of adaptations.

    
    properties (Access = private)
        % for app setup and loading data
        frame = 1; % frame of video
        Intensity = NaN;% Description
        CellLocs = NaN; % Description
        ImgStack = NaN; % Description 
        FrameRate = 20; % Description
        total_imgs = 1;
        x = 1;
        y = 1;
        NearestPt = 1;
        SmoothWindow = 20; % size of window to smooth to
        PlotData = NaN; % used to plot single cell data
        ObservedCells = zeros(1,3); % list of cells to save when "save cell ID list" is clicked
        CellList = zeros(1,3); % list of cells within box, one cell per row, formatted [x y cellID]
        fname = NaN; % filename
        pname = NaN; % pathname
        AfterImpactIntensity = NaN;
        
        % Plotting
        ImpactIntensity = NaN;
        greencolormap = horzcat(zeros(256,1),linspace(0,1,256)',zeros(256,1)); % to display impact image with green color
        box = 1; % select area box (rectangle object with [xmin, ymin, width, height]. xmin and ymin = upper left corner of the rectangle)
        PlotTime = 1; % time (x-axis) of click
        PlotPixInt = 1; % pixel intensity (y-axis) of click
        GMModel; % gaussian mixed model (fit to two means)
        ClickedPoint = 1; % location of clicked point (point object)
        green_impact;
        temptime_coord;
        currentaxis;
        pointx;
        pointy;
        
        % timing
        impact_time; % equally spaced vector that goes from zero to end of impact, equal to number of frames 
        postimpact_time; % equally spaced vector that goes from zero to end of post impact, equal to number of frames
        impact_spf = 0.084; % impact seconds per frame (25ms exposure, calculated from timings)
        postimpact_mpf = 0.36; % post impact minutes per frame (calculated from total time)
        time;
        
        % histogram sorting
        low_blue; % alive
        middle_blue; % schrodinger's cat
        high_blue; % dead
        
        ismiddle %  =1 if the middle cells are being plotted on UIAxes
    end
    
    properties (Access = public)
        % red, green, and blue for all cells within selected area
        red_data = NaN;
        green_data = NaN;
        blue_data = NaN;
    end
    
    methods (Access = private)
        
        % consider changing white circle size? may not work at different app sizes
        function [x, y] = SelectCell(~, ax) % check to make sure it works
            % select a single cell in the image
            % uses drawpoint instead of button down and ginput because otherwise draw rectangle wouldn't work
            roi = drawpoint(ax, 'Color', 'black',"Deletable",true, 'Visible',"off");
            x = roi.Position(1);
            y = roi.Position(2);
            hold(ax,'on')
            scatter(ax,x,y,150,'o','MarkerEdgeColor','white');
            hold(ax,'off')
        end
        
        function CellFromCoord(~, ax, xcoord, ycoord) % check
            roi = drawpoint(ax, 'Position',[xcoord, ycoord], 'Color', 'black',"Deletable",true, 'Visible',"off");
            hold(ax,'on')
            scatter(ax,xcoord,ycoord,150,'o','MarkerEdgeColor','white');
            hold(ax,'off')
        end        
        
        function DeleteCell(~, ax) % check to make sure it works
            % deleted drawn point
            pts = findobj(ax,'Type','images.roi.Point');
            delete(pts);
            % deletes circle from scatter plot drawn on image
            circle = findobj(ax,'Type', 'scatter');
            delete(circle);
        end
        
        function ClearAxes(app, ~)
            % clears all plot axes, plot titles, and plot legends
            cla(app.GreenAxes)
            cla(app.RedAxes)
            cla(app.BlueAxes)
            axis(app.GreenAxes,'off');
            axis(app.RedAxes,'off');
            axis(app.BlueAxes,'off');
            app.GreenAxes.Title.String = '';
            app.RedAxes.Title.String = '';
            app.BlueAxes.Title.String = '';
            legend(app.GreenAxes,'off');
            legend(app.RedAxes,'off');
            legend(app.BlueAxes,'off');
        end
        
        function PlotOneGreen(app, OneGreenImpact, OneGreenAfterImpact, SmoothWindow)
            % unchanged
            
            ClearAxes(app);
            %plot on UIAxes2
            axis(app.GreenAxes,'on')
            hold(app.GreenAxes,'on')
            app.impact_time = linspace(0,size(OneGreenImpact,1)*app.impact_spf,size(OneGreenImpact,1));
            app.GreenAxes.XLim = [0 app.impact_time(end,1)];
            app.GreenAxes.XLabel.String = 'Time (s)';
            app.GreenAxes.Title.String = 'Impact';
            plot(app.GreenAxes,app.impact_time,smoothdata(OneGreenImpact(:,1),'movmean',SmoothWindow),'LineWidth',4,'Color','g');
            legend(app.GreenAxes,'Calcium Concentration');
            
            % plot after impact data on UIAxes 3
            axis(app.RedAxes,'on')
            hold(app.RedAxes,"on")
            app.postimpact_time = linspace(0,size(OneGreenAfterImpact,1)*app.postimpact_mpf,size(OneGreenAfterImpact,1));
            app.RedAxes.XLim = [0 app.postimpact_time(end,1)];
            app.RedAxes.XLabel.String = 'Time (min)';
            app.RedAxes.Title.String = 'Post-impact Intensities';
            plot(app.RedAxes,app.postimpact_time,smoothdata(OneGreenAfterImpact(:,1),'movmean',SmoothWindow),'LineWidth',4,'Color','r');
            plot(app.RedAxes,app.postimpact_time,smoothdata(OneGreenAfterImpact(:,2),'movmean',SmoothWindow),'LineWidth',4,'Color','g');
            plot(app.RedAxes,app.postimpact_time,smoothdata(OneGreenAfterImpact(:,3),'movmean',SmoothWindow),'LineWidth',4,'Color','b');
            legend(app.RedAxes,'MT Polarity','Calcium Concentration','Cell Death');
        end
        
        function PlotOneRGB(app, PlotData, SmoothWindow)
            % unchanged
            
            ClearAxes(app);
            % plot on UIAxes2
            axis(app.GreenAxes,'on')
            hold(app.GreenAxes,"on")
            %app.postimpact_time = linspace(0,app.total_imgs*app.postimpact_mpf,app.total_imgs);
            app.GreenAxes.XLim = [0 app.time(end,1)];
            app.GreenAxes.XLabel.String = 'Time (min)';
            app.GreenAxes.Title.String = 'Cell Stain Intensities';
            plot(app.GreenAxes,app.time,smoothdata(PlotData(:,1),'movmean',SmoothWindow),'LineWidth',4,'Color','r');
            plot(app.GreenAxes,app.time,smoothdata(PlotData(:,2),'movmean',SmoothWindow),'LineWidth',4,'Color','g');
            plot(app.GreenAxes,app.time,smoothdata(PlotData(:,3),'movmean',SmoothWindow),'LineWidth',4,'Color','b');
            legend(app.GreenAxes,'MT Polarity','Calcium Concentration','Cell Death');
        end
        
        function PlotMultipleGreen(app, green, SmoothWindow, bold)
            % unchanged
            
            % find average
            green_avg = mean(green,2);
            ClearAxes(app);
            app.impact_time = linspace(0,app.total_imgs*app.impact_spf,app.total_imgs); % setup time axis

            % plot on UIAxes2
            axis(app.GreenAxes,'on')
            hold(app.GreenAxes,"on")
            app.impact_time = linspace(0,app.total_imgs*app.impact_spf,app.total_imgs);
            app.GreenAxes.XLim = [0 app.total_imgs*app.impact_spf];
            app.GreenAxes.XLabel.String = 'Time (s)';
            app.GreenAxes.Title.String = 'Calcium Concentration';
            plot(app.GreenAxes,app.impact_time,smoothdata(green_avg,'movmean',SmoothWindow),'LineWidth',3,'Color','g');
            for i = 1:size(green,2)
                plot(app.GreenAxes,app.impact_time,smoothdata(green(:,i),'movmean',SmoothWindow),'Linewidth',0.5,'Color',[0.529 1 0.5529]);
            end
            
            % decide which line to bold: either mean (dashed) or selected line from click (bold)
            if strcmp(bold, 'mean') == 1
                plot(app.GreenAxes,app.impact_time,smoothdata(green_avg,'movmean',SmoothWindow),'LineWidth',3,'Color','g', 'LineStyle', '--');
            elseif strcmp(bold, 'selected') == 1
                plot(app.GreenAxes,app.impact_time,smoothdata(green(:,app.ClickedPoint),'movmean',SmoothWindow),'LineWidth',3,'Color','g');
            end
        end
        
        function PlotMultipleRGB(app, green, red, blue, SmoothWindow, bold)
            % unchanged
            
            % takes list of cells input, then extracts the intensity curves for all cells inside the box
            % plots each cell as a line, and the average as a bolded line
            
            red_avg = mean(red,2);
            green_avg = mean(green,2);
            blue_avg = mean(blue,2);
            
            % decide which line to bold: either mean (dashed) or selected line from click (bold)
            if strcmp(bold, 'mean') == 1
                red_bold = red_avg;
                green_bold = green_avg;
                blue_bold = blue_avg;
                linestyle = '--';
            elseif strcmp(bold, 'selected') == 1
                red_bold = red(:,app.ClickedPoint);
                green_bold = green(:,app.ClickedPoint);
                blue_bold = blue(:,app.ClickedPoint);
                linestyle = '-';
            end
            ClearAxes(app);
            
            app.postimpact_time = linspace(0,app.total_imgs*app.postimpact_mpf,app.total_imgs); % setup time axis
            
            % plot red
            axis(app.RedAxes,'on')
            hold(app.RedAxes,"on")
            app.RedAxes.Title.String = 'Mitochondrial Polarity';
            app.RedAxes.XLim = [0 app.time(end,1)];
            app.RedAxes.XLabel.String = 'Time (min)';
            for i = 1:size(red,2)
                plot(app.RedAxes,app.time,smoothdata(red(:,i),'movmean',SmoothWindow),'Linewidth',0.5,'Color',[1 0.529 0.529]);
            end
            plot(app.RedAxes,app.time,smoothdata(red_bold,'movmean',SmoothWindow),'LineWidth',3,'Color','r','LineStyle', linestyle);
            legend(app.RedAxes,"hide");
            hold(app.RedAxes,"off")
            % plot green
            axis(app.GreenAxes,'on')
            hold(app.GreenAxes,"on")
            app.GreenAxes.Title.String = 'Calcium Concentration';
            app.GreenAxes.XLim = [0 app.time(end,1)];
            app.GreenAxes.XLabel.String = 'Time (min)';
            for i = 1:size(green,2)
                plot(app.GreenAxes,app.time,smoothdata(green(:,i),'movmean',SmoothWindow),'Linewidth',0.5,'Color',[0.529 1 0.5529]);
            end
            plot(app.GreenAxes,app.time,smoothdata(green_bold,'movmean',SmoothWindow),'LineWidth',3,'Color','g', 'LineStyle', linestyle);
            legend(app.GreenAxes,"hide");
            hold(app.GreenAxes,"off")
            % plot blue
            axis(app.BlueAxes,'on')
            hold(app.BlueAxes,"on")
            app.BlueAxes.Title.String = 'Cell Death';
            app.BlueAxes.XLim = [0 app.time(end,1)];
            app.BlueAxes.XLabel.String = 'Time (min)';
            for i = 1:size(blue,2)
                plot(app.BlueAxes,app.time,smoothdata(blue(:,i),'movmean',SmoothWindow),'Linewidth',0.5,'Color',[0.529 0.756 1]);
            end
            plot(app.BlueAxes,app.time,smoothdata(blue_bold,'movmean',SmoothWindow),'LineWidth',3,'Color','b', 'LineStyle', linestyle);
            legend(app.BlueAxes,"hide");
            hold(app.BlueAxes,"off")
        end
        
        function HighlightCell(app, green, red, blue, list)
            % check
            
            % assign plot time and intensity from button down function
            app.PlotTime = app.pointx;
            app.PlotPixInt = app.pointy;
            
            CurrentFrame = dsearchn(app.time,app.PlotTime); % find current frame from list of times
            % find correct point in axes clicked
            switch app.currentaxis % seems like switch works with these app.ImageAxes labels
                % app.ClickedPoint is then used in the PlotMultipleRGB code, only initialized here, not passed, gives index within green_data
                % which is NOT the cell ID but the index value within app.CellList
                case app.GreenAxes
                    app.ClickedPoint = dsearchn(green(CurrentFrame,:)',app.PlotPixInt);
                case app.RedAxes
                    app.ClickedPoint = dsearchn(red(CurrentFrame,:)', app.PlotPixInt);
                case app.BlueAxes
                    app.ClickedPoint = dsearchn(blue(CurrentFrame,:)', app.PlotPixInt);
            end
            if strcmp(app.fname,'impact.tif') == 1 % if impact video, then size of ImgStack is different - only green
                PlotMultipleGreen(app, green, app.SmoothWindow, 'selected');
            else
                PlotMultipleRGB(app, green, red, blue, app.SmoothWindow, 'selected');
            end
            
            % get cell ID#, location, and plot white circle
            app.CellIDEditField.Value = list(app.ClickedPoint,3);
            % need to initialize temptime_coord
            temptime = app.CellLocs(round(app.frame),:);
            app.temptime_coord = zeros(size(temptime,2)/2,2);
            for i = 1:size(app.temptime_coord,1)
                app.temptime_coord(i,1) = temptime(1,i*2-1);
                app.temptime_coord(i,2) = temptime(1,i*2);
            end
            % plot white circle on image
            CellFromCoord(app, app.ImageAxes, app.temptime_coord(app.CellIDEditField.Value,1),app.temptime_coord(app.CellIDEditField.Value,2))
            % update field to display coordinates of chosen cell
            app.ClosestCellxEditField.Value = app.temptime_coord(app.CellIDEditField.Value,1); 
            app.ClosestCellyEditField.Value = app.temptime_coord(app.CellIDEditField.Value,2);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %movegui(app.UIFigure,"center");
            %drawnow;
            app.UIFigure.WindowState = 'maximized';
            set(0,'DefaultLegendAutoUpdate','off');
            
            % attempt to stop image from being draggable
            disableDefaultInteractivity(app.ImageAxes);
            app.ImageAxes.HitTest = 'off';
            
            % hide axes
            axis(app.GreenAxes,'off');
            axis(app.RedAxes,'off');
            axis(app.BlueAxes,'off');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {1006, 1006};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {1085, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: SelectvideoButton
        function SelectvideoButtonPushed(app, event)
            % not modified
            
            
            % use UI function to get image file and folder location
            [app.fname,app.pname] = uigetfile('*.tif');             
            drawnow; % fixes issue where app goes into background after uigetfile is called
            figure(app.UIFigure)
            
            % get image info
            info = imfinfo(fullfile(app.pname,app.fname));
            rowdim = info.Height;
            coldim = info.Width;
            app.total_imgs = numel(info);
            app.FrameSlider.Limits = [0 app.total_imgs]; % set frame slider limits based on total frames in video
            
                        
            % initialize stack in which image is kept
            if strcmp(app.fname,'impact.tif') == 1 % if impact vidoe, then size of ImgStack is different - only green
                app.ImgStack = zeros(rowdim, coldim, app.total_imgs, 'uint8');
                AfterImpactIntensityLoad = load(fullfile(app.pname,'pos1_reg_intensity.mat'));
                app.AfterImpactIntensity = AfterImpactIntensityLoad.intensity_mat(:,2:end);
            elseif strcmp(app.fname,'pos1_reg.tif') == 1
                app.ImgStack = zeros(rowdim, coldim, 3, app.total_imgs, 'uint8');
                ImpactIntensityLoad = load(fullfile(app.pname,'impact_intensity.mat'));
                app.ImpactIntensity = ImpactIntensityLoad.intensity_mat(:,2:end);
            else
                app.ImgStack = zeros(rowdim, coldim, 3, app.total_imgs, 'uint8');
            end
                        
            % indeterminate loading bar for video and data
            d = uiprogressdlg(app.UIFigure,'Title','Loading','Indeterminate','on'); 
            
            % Create Tif Object, load video, and display on UIAxes1
            TifObj = Tiff(fullfile(app.pname,app.fname), 'r');
            for i = 1:app.total_imgs
                TifObj.setDirectory(i);
                if strcmp(app.fname,'impact.tif') == 1 % if impact video, read differently
                    app.ImgStack(:,:,i) = TifObj.read();
                else
                    app.ImgStack(:,:,:,i) = TifObj.read();
                end
            end
            close(TifObj);
            
            % read in intensity and location data from same folder
            fname_data = strcat(erase(app.fname,'.tif'),'_intensity.mat');
            IntensityLoad = load(fullfile(app.pname,fname_data));
            % dynamically extract variable name from IntensityLoad
            intensityname = fieldnames(IntensityLoad); % get the field names of the struct
            intensityfield = intensityname{1,1};
            tempintensity = getfield(IntensityLoad, intensityfield);
            app.Intensity= tempintensity(:,2:end); % extract intensity
            app.time = tempintensity(:,1); % time column
            % get cell locations
            fname_locs = strcat(erase(app.fname,'.tif'),'_locs.mat');
            CellLocsLoad = load(fullfile(app.pname,fname_locs));
            locsname = fieldnames(CellLocsLoad);
            locsfield = locsname{1,1};
            app.CellLocs = getfield(CellLocsLoad, locsfield);

            % close loading bar
            close(d);

        end

        % Value changed function: PlayPauseButton
        function PlayPauseButtonValueChanged(app, event)
            % not modified
            
            while app.PlayPauseButton.Value == 1 % when play button is clicked, it plays
                disableDefaultInteractivity(app.ImageAxes);
                if app.frame > app.total_imgs % if playback reaches end of video, loop to beginning
                    app.frame = 1;
                end
                if strcmp(app.fname,'impact.tif') == 1 % shows different sized ImgStack for impact
                    imshow(app.ImgStack(:,:,round(app.frame)), app.greencolormap, 'Parent', app.ImageAxes);
                else
                    imshow(app.ImgStack(:,:,:,round(app.frame)), 'Parent', app.ImageAxes);
                end
                frame_increment = round(app.FrameRate/5);
                app.frame = app.frame+frame_increment; % increment by the framerate
                drawnow % draw instantly

                app.FrameSlider.Value = app.frame; % slider value changes as frame changes
            end
            app.PlayPauseButton.Value = 0; % when play button is unclicked, it pauses

        end

        % Button pushed function: SelectCellFromImageButton
        function SelectCellFromImageButtonPushed(app, event)
            % check
            
            % delete any previous cells, could modify this to be a button
            DeleteCell(app,app.ImageAxes);
            
            % call function to draw cell on image
            [app.x,app.y] = SelectCell(app, app.ImageAxes);
            hold(app.ImageAxes,"on")
            % scatter(app.ImageAxes,app.x,app.y,75,'white',"x"); % plot at coordinates of clicked location, optional
            app.XCoordinateEditField.Value = app.x; % display clicked coordinates in field
            app.YCoordinateEditField.Value = app.y;
            
            % Sort current frame cell coordinates into mxn matrix for dsearchn
            temptime = app.CellLocs(round(app.frame),:);
            app.temptime_coord = zeros(size(temptime,2)/2,2);
            for i = 1:size(app.temptime_coord,1)
                app.temptime_coord(i,1) = temptime(1,i*2-1);
                app.temptime_coord(i,2) = temptime(1,i*2);
            end
            
            % find nearest cell, gives row index within temptime
            app.NearestPt = dsearchn(app.temptime_coord,[app.x app.y]); 
            %scatter(app.ImageAxes,app.temptime_coord(app.NearestPt,1),app.temptime_coord(app.NearestPt,2),150,'white',"o"); % plot marker at coordinates of nearest cell
            app.ClosestCellxEditField.Value = app.temptime_coord(app.NearestPt,1); % display coordinates of nearest cell
            app.ClosestCellyEditField.Value = app.temptime_coord(app.NearestPt,2);
            app.CellIDEditField.Value = app.NearestPt; % row index should correspond to cell ID #
            app.ObservedCells = vertcat(app.ObservedCells,horzcat(app.NearestPt,app.temptime_coord(app.NearestPt,1),app.temptime_coord(app.NearestPt,2)));
            
            if strcmp(app.fname,'impact.tif') == 1 % if impact video, also plot post-impact site
                ImpactData = app.Intensity(:,app.NearestPt);
                AfterImpactData = app.AfterImpactIntensity(:,(app.NearestPt*3-2):(app.NearestPt*3));
                PlotOneGreen(app,ImpactData,AfterImpactData,app.SmoothWindow);
            elseif strcmp(app.fname,'pos1_reg.tif') == 1
                ImpactData = app.ImpactIntensity(:,app.NearestPt);
                AfterImpactData = app.Intensity(:,(app.NearestPt*3-2):(app.NearestPt*3));
                PlotOneGreen(app,ImpactData,AfterImpactData,app.SmoothWindow);
            else
                app.PlotData = app.Intensity(:,(app.NearestPt*3-2):(app.NearestPt*3));
                PlotOneRGB(app, app.PlotData, app.SmoothWindow);
            end


        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            % not modified
            app.frame = event.Value; % frame is set to the value of the slider
            app.PlayPauseButton.Value = 0; % when slider is moved, stop playback
            if app.frame < 1
                app.frame = 1;
            end
            disableDefaultInteractivity(app.ImageAxes);
            if strcmp(app.fname,'impact.tif') == 1
                imshow(app.ImgStack(:,:,ceil(app.frame)), app.greencolormap, 'Parent', app.ImageAxes);
            else
                imshow(app.ImgStack(:,:,:,ceil(app.frame)), 'Parent', app.ImageAxes);
            end

        end

        % Value changed function: FramerateEditField
        function FramerateEditFieldValueChanged(app, event)
            % not modified
            
            app.FrameRate = app.FramerateEditField.Value;
            if app.FrameRate < 5
                uialert(app.UIFigure,'Framerate minimum of 5 fps, use left/right arrow keys to move individual frames','Invalid Framerate');
                app.FrameRate = 5;
                app.FramerateEditField.Value = 5;
            end
        end

        % Value changed function: SmoothingwindowsizeEditField
        function SmoothingwindowsizeEditFieldValueChanged(app, event)
            % not modified
            
            % changes smoothing window size
            app.SmoothWindow = app.SmoothingwindowsizeEditField.Value;
        end

        % Button pushed function: SaveCellIDListButton
        function SaveCellIDListButtonPushed(app, event)
            % not modified
            
            app.ObservedCells(1,:) = [];
            SaveCellList = app.ObservedCells;
            uisave('SaveCellList',strcat(erase(app.fname,'.tif'),'_observed_cells.mat'));
        end

        % Button pushed function: SelectcellfromcoordinatesButton
        function SelectcellfromcoordinatesButtonPushed(app, event)
            % check, think it's done
            
            % allows plotting if x and y coordinates are entered into the boxes            
            app.x = app.XCoordinateEditField.Value;
            app.y = app.YCoordinateEditField.Value;
            
            % call function to draw on image
            CellFromCoord(app, app.ImageAxes, app.x, app.y);
            
            % Sort current frame cell coordinates into mxn matrix for dsearchn
            temptime = app.CellLocs(round(app.frame),:);
            app.temptime_coord = zeros(size(temptime,2)/2,2);
            for i = 1:size(app.temptime_coord,1)
                app.temptime_coord(i,1) = temptime(1,i*2-1);
                app.temptime_coord(i,2) = temptime(1,i*2);
            end
            
            % find nearest cell, gives row index within temptime
            app.NearestPt = dsearchn(app.temptime_coord,[app.x app.y]); 
            app.ClosestCellxEditField.Value = app.temptime_coord(app.NearestPt,1); % display coordinates of nearest cell
            app.ClosestCellyEditField.Value = app.temptime_coord(app.NearestPt,2);        
            app.CellIDEditField.Value = app.NearestPt; % row index should correspond to cell ID #
            app.ObservedCells = vertcat(app.ObservedCells,horzcat(app.NearestPt,app.temptime_coord(app.NearestPt,1),app.temptime_coord(app.NearestPt,2)));
            
            if strcmp(app.fname,'impact.tif') == 1 % if impact video, also plot post-impact site
                ImpactData = app.Intensity(:,app.NearestPt);
                AfterImpactData = app.AfterImpactIntensity(:,(app.NearestPt*3-2):(app.NearestPt*3));
                PlotOneGreen(app,ImpactData,AfterImpactData,app.SmoothWindow);
            elseif strcmp(app.fname,'pos1_reg.tif') == 1
                ImpactData = app.ImpactIntensity(:,app.NearestPt);
                AfterImpactData = app.Intensity(:,(app.NearestPt*3-2):(app.NearestPt*3));
                PlotOneGreen(app,ImpactData,AfterImpactData,app.SmoothWindow);
            else
                app.PlotData = app.Intensity(:,(app.NearestPt*3-2):(app.NearestPt*3));
                PlotOneRGB(app, app.PlotData, app.SmoothWindow);
            end

        end

        % Button pushed function: SelectAreaButton
        function SelectAreaButtonPushed(app, event)
            % check
            
            % delete any existing cells
            DeleteCell(app, app.ImageAxes);
            
            % draw rectangle
            hold(app.ImageAxes,"on")
            app.box = drawrectangle(app.ImageAxes);
            hold(app.ImageAxes,"off")
            
            app.ismiddle = 0;
            
            % find all cells inside box
            temptime = app.CellLocs(round(app.frame),:);
            for i = 1:size(temptime,2)/2
                temptime_x = temptime(1,i*2-1);
                temptime_y = temptime(1,i*2);
                if app.box.Position(1) <= temptime_x && temptime_x <= app.box.Position(1)+app.box.Position(3) && app.box.Position(2) <= temptime_y && temptime_y <= app.box.Position(2)+app.box.Position(4)
                    app.CellList = vertcat(app.CellList,horzcat(temptime_x,temptime_y,i));
                end
            end
            app.CellList(1,:) = [];
            if isempty(app.CellList) == 1 % if box contains no cells, then throw an error message
                uialert(app.UIFigure,'No cells found','Invalid Area Selected');
                delete(app.box);
            end
            
            % get data and calculate averages
            if strcmp(app.fname,'impact.tif') == 1 % if intensity video, then no need to plot all three, just green
                app.green_data = zeros(size(app.Intensity,1),size(app.CellList,1));
                for i = 1:size(app.CellList,1)
                    app.green_data(:,i) = app.Intensity(:,app.CellList(i,3));
                end
                PlotMultipleGreen(app, app.green_data, app.SmoothWindow, 'mean');
            else % else intensity has three columns for each plot, then plot all three colors
                % initialize and extract data
                app.red_data = zeros(size(app.Intensity,1),size(app.CellList,1));
                app.green_data = zeros(size(app.Intensity,1),size(app.CellList,1));
                app.blue_data = zeros(size(app.Intensity,1),size(app.CellList,1));
                app.green_impact = zeros(size(app.ImpactIntensity,1),size(app.CellList,1));
                for i = 1:size(app.CellList,1)
                    app.red_data(:,i) = app.Intensity(:,app.CellList(i,3)*3-2);
                    app.green_data(:,i) = app.Intensity(:,app.CellList(i,3)*3-1);
                    app.blue_data(:,i) = app.Intensity(:,app.CellList(i,3)*3);
                    if strcmp(app.fname, 'pos1_reg.tif') == 1
                        app.green_impact(:,i) = app.ImpactIntensity(:,app.CellList(i,3));
                    end
                end
                PlotMultipleRGB(app, app.green_data, app.red_data, app.blue_data, app.SmoothWindow, 'mean')
            end
            app.ObservedCells = vertcat(app.ObservedCells,app.CellList); % add cells to observed cells
        end

        % Button pushed function: ClearCurrentDataButton
        function ClearCurrentDataButtonPushed(app, event)
            % check
            
            ClearAxes(app);
            if isobject(app.box)
                delete(app.box);
            end
            app.CellList = zeros(1,3);
            
            DeleteCell(app, app.ImageAxes);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            % currently unchanged
            
            
            % allows left and right arrow keys to move frame by frame
            % must first click into body of app before using or it will not work
            key = event.Key;
            if strcmp(key,'rightarrow') == 1 % if right arrow is pressed
                if app.frame > app.total_imgs % if the frame is about to be greater than the total images, loop back to beginning
                    app.frame = 1;
                end
                app.frame = app.frame + 1; % otherwise just increment by one
            elseif strcmp(key,'leftarrow') == 1 % if left arrow is pressed
                if app.frame == 1 % if on frame one then do not change frames
                    app.frame = 1;
                else
                    app.frame = app.frame - 1; % otherwise deincrement the frame by one
                end
            end
            
            % show images
            if strcmp(app.fname,'impact.tif') == 1 % show impact image with green colormap
                imshow(app.ImgStack(:,:,ceil(app.frame)), app.greencolormap, 'Parent', app.ImageAxes);
            else % show regular image
                imshow(app.ImgStack(:,:,:,ceil(app.frame)), 'Parent', app.ImageAxes);
            end
            
            app.FrameSlider.Value = app.frame; % slider value changes as frame changes
            drawnow % draw instantly
        end

        % Value changed function: CellIDEditField
        function CellIDEditFieldValueChanged(app, event)
            % check
            
            % use the value changed in the Cell ID field to to pull up and plot the cell
            
            % Sort current frame cell coordinates into mxn matrix for displaying coordinates
            temptime = app.CellLocs(round(app.frame),:);
            app.temptime_coord = zeros(size(temptime,2)/2,2);
            for i = 1:size(app.temptime_coord,1)
                app.temptime_coord(i,1) = temptime(1,i*2-1);
                app.temptime_coord(i,2) = temptime(1,i*2);
            end
            
            app.NearestPt = app.CellIDEditField.Value; % row index should correspond to cell ID #
%             hold(app.ImageAxes,"on")
%             % plot white circle at site of cell
%             scatter(app.ImageAxes,app.temptime_coord(app.CellIDEditField.Value,1),app.temptime_coord(app.CellIDEditField.Value,2),150,'white',"o");
%             hold(app.ImageAxes,"off")
            
            % plot cell onto image
            CellFromCoord(app,app.ImageAxes,app.temptime_coord(app.CellIDEditField.Value,1),app.temptime_coord(app.CellIDEditField.Value,2))
            
            % add observed cell to list
            app.ObservedCells = vertcat(app.ObservedCells,horzcat(app.temptime_coord(app.CellIDEditField.Value,1),app.temptime_coord(app.CellIDEditField.Value,2),app.CellIDEditField.Value));
            % change the x and y fields to reflect the current cell
            app.ClosestCellxEditField.Value = app.temptime_coord(app.CellIDEditField.Value,1);
            app.ClosestCellyEditField.Value = app.temptime_coord(app.CellIDEditField.Value,2);
            
            % plot
            if strcmp(app.fname,'impact.tif') == 1 % if impact video, also plot post-impact site
                ImpactData = app.Intensity(:,app.NearestPt);
                AfterImpactData = app.AfterImpactIntensity(:,(app.NearestPt*3-2):(app.NearestPt*3));
                PlotOneGreen(app,ImpactData,AfterImpactData,app.SmoothWindow);
            elseif strcmp(app.fname,'pos1_reg.tif') == 1
                ImpactData = app.ImpactIntensity(:,app.NearestPt);
                AfterImpactData = app.Intensity(:,(app.NearestPt*3-2):(app.NearestPt*3));
                PlotOneGreen(app,ImpactData,AfterImpactData,app.SmoothWindow);
            else
                app.PlotData = app.Intensity(:,(app.NearestPt*3-2):(app.NearestPt*3));
                PlotOneRGB(app, app.PlotData, app.SmoothWindow);
            end

        end

        % Button pushed function: TimepointHistogramsButton
        function TimepointHistogramsButtonPushed(app, event)
            % need to do
            
            % [app.PlotTime,app.PlotPixInt,tempaxes] = UIFigureWindowButtonDown(app, 2);
            
            app.PlotTime = app.pointx;
            app.PlotPixInt = app.pointy;
            
            % get all intensity points from app.PlotTime
            CurrentFrame = dsearchn(app.time,app.PlotTime); % find current frame from list of times
            
            % make popout figure for histograms
            hist_popout = figure('Name','Histograms','Position',[100 500 1800 500]);
            
            tiledlayout(hist_popout,1,3);
            nexttile;
            [hg1, hg2] = hist(app.green_data(CurrentFrame,:));
            hg = bar(hg2, hg1, 'hist');
            set(hg, 'FaceColor', 'g', 'EdgeColor', 'black');
            set(gca, 'FontSize', 14, 'FontWeight', 'bold');
            title('Calcium Intensity','FontSize',14);
            xlabel('Intensity');
            ylabel('Count');
            
            nexttile;
            [hr1, hr2] = hist(app.red_data(CurrentFrame,:));
            hr = bar(hr2, hr1, 'hist');
            set(hr, 'FaceColor', 'r', 'EdgeColor', 'black');
            set(gca, 'FontSize', 14, 'FontWeight', 'bold');
            title('Polarized Mitochondria','FontSize',14);
            xlabel('Intensity');
            ylabel('Count');
            
            % fit to mixed gaussian model with two means
            app.GMModel = fitgmdist(app.blue_data(CurrentFrame,:)',2); 
            
            nexttile;
            [hb1, hb2] = hist(app.blue_data(CurrentFrame,:));
            hb = bar(hb2, hb1, 'hist');
            set(hb, 'FaceColor', 'b', 'EdgeColor', 'black');
            set(gca, 'FontSize', 14, 'FontWeight', 'bold');
            title('Nuclear Membrane Permeability','FontSize',14);
            xlabel('Intensity');
            ylabel('Count');
            
            % create a variable to tell plotting whether or not it's one distribution or two
            num_dist = 0; % zero if all low, 1 if all high, 2 if two distributions
           
            % need to smooth before finding
            temp_smooth_blue = zeros(size(app.blue_data,1),size(app.blue_data,2));
            for i = 1:size(app.blue_data,2)
                temp_smooth_blue(:,i) = smoothdata(app.blue_data(:,i),'movmean',app.SmoothWindow);
            end
            
            % extract the indices of cells with intensity lower than low mu and higher than high mu
            if app.GMModel.mu(1) < app.GMModel.mu(2) % if mu 1 is the lower mu
                if app.GMModel.mu(1) > 60 % all high blue
                    app.low_blue = NaN; % set low blue to NaN
                    app.high_blue = linspace(1,size(app.blue_data,2),size(app.blue_data,2))'; % plot everything in high blue
                    num_dist = 1;
                elseif app.GMModel.mu(2) < 50 % all low blue
                    app.low_blue = linspace(1,size(app.blue_data,2),size(app.blue_data,2))';
                    app.high_blue = NaN;
                    num_dist = 0;
                else
                    app.low_blue = find(temp_smooth_blue(CurrentFrame,:)' < app.GMModel.mu(1) + 10); % low blue is a list of indices from app.blue_data
                    app.high_blue = find(temp_smooth_blue(CurrentFrame,:)' > app.GMModel.mu(2));
                    app.middle_blue = find(temp_smooth_blue(CurrentFrame,:)' > app.GMModel.mu(1) + 10 & temp_smooth_blue(CurrentFrame,:)' < app.GMModel.mu(2));
                    num_dist = 2;
                end
            elseif app.GMModel.mu(1) > app.GMModel.mu(2) % if mu 2 is the lower mu
                if app.GMModel.mu(2) > 60 % all high blue
                    app.low_blue = NaN;
                    app.high_blue = linspace(1,size(app.blue_data,2),size(app.blue_data,2))';
                    num_dist = 1;
                elseif app.GMModel.mu(1) < 50 % all low blue
                    app.low_blue = linspace(1,size(app.blue_data,2),size(app.blue_data,2))';
                    app.high_blue = NaN;
                    num_dist = 0;
                else
                    app.high_blue = find(temp_smooth_blue(CurrentFrame,:)' > app.GMModel.mu(1));
                    app.low_blue = find(temp_smooth_blue(CurrentFrame,:)' < app.GMModel.mu(2) + 10);
                    app.middle_blue = find(temp_smooth_blue(CurrentFrame,:)' > app.GMModel.mu(2) + 10 & temp_smooth_blue(CurrentFrame,:)' < app.GMModel.mu(1));
                    num_dist = 2;
                end
            end      
           
            % make a new figure window for cell subpopulation plots, but keep minimized
            model_popout = figure('Name','Cell Subpopulations', 'Position', [0 0 2200 1000]);
            model_popout.WindowState = 'minimized';

            % initialize a 2 by 3 tiled layout
            tiledlayout(model_popout,3,3);
            
            % plot population 1 (low blue) green
            nexttile;
            hold on
            for i = 1:size(app.low_blue,1)
                if num_dist == 0 || num_dist == 2 % only plot if all low blue or two populations
                    plot(app.time,smoothdata(app.green_data(:,app.low_blue(i)),'movmean', app.SmoothWindow),'Linewidth',0.5,'Color',[0 0.75 0]);
                end
            end
            xlim([0 app.time(end,1)]);
            ylim([0 256]);
            set(gca,'FontSize',14,'FontWeight','bold');
            title('Calcium Concentration for Low Blue Intensity Cells');
            xlabel('Time (min)');
            ylabel('Pixel Intensity');
            hold off
            
            % plot middle blue green
            nexttile;
            hold on
            for i = 1:size(app.middle_blue,1)
                if num_dist == 0 || num_dist == 2 % only plot if all low blue or two populations
                    plot(app.time,smoothdata(app.green_data(:,app.middle_blue(i)),'movmean', app.SmoothWindow),'Linewidth',0.5,'Color',[0 0.75 0]);
                end
            end
            xlim([0 app.time(end,1)]);
            ylim([0 256]);
            set(gca,'FontSize',14,'FontWeight','bold');
            title('Calcium Concentration for Medium Blue Intensity Cells');
            xlabel('Time (min)');
            ylabel('Pixel Intensity');
            hold off
            
             % plot population 2 (high blue) green
            nexttile;
            hold on
            for i = 1:size(app.high_blue,1)
                if num_dist == 1 || num_dist == 2 % only plot if all high blue or two populations
                    plot(app.time,smoothdata(app.green_data(:,app.high_blue(i)),'movmean', app.SmoothWindow),'Linewidth',0.5,'Color',[0 0.75 0]);
                end
            end
            xlim([0 app.time(end,1)]);
            ylim([0 256]);
            set(gca,'FontSize',14,'FontWeight','bold');
            title('Calcium Concentration for High Blue Intensity Cells');
            xlabel('Time (min)');
            ylabel('Pixel Intensity');
            hold off
            
            % plot population 1 (low blue) red
            nexttile;
            hold on
            for i = 1:size(app.low_blue,1)
                if num_dist == 0 || num_dist == 2 % only plot if all low blue or two populations
                    plot(app.time,smoothdata(app.red_data(:,app.low_blue(i)),'movmean', app.SmoothWindow),'Linewidth',0.5,'Color',[0.9 0 0]);
                end
            end
            xlim([0 app.time(end,1)]);
            ylim([0 256]);
            set(gca,'FontSize',14,'FontWeight','bold');
            title('Mitochondrial Polarity for Low Blue Intensity Cells');
            xlabel('Time (min)');
            ylabel('Pixel Intensity');
            hold off
            
            % plot middle blue red
            nexttile;
            hold on
            for i = 1:size(app.middle_blue,1)
                if num_dist == 0 || num_dist == 2 % only plot if all low blue or two populations
                    plot(app.time,smoothdata(app.red_data(:,app.middle_blue(i)),'movmean', app.SmoothWindow),'Linewidth',0.5,'Color',[0.9 0 0]);
                end
            end
            xlim([0 app.time(end,1)]);
            ylim([0 256]);
            set(gca,'FontSize',14,'FontWeight','bold');
            title('Mitochondrial Polarity for Medium Blue Intensity Cells');
            xlabel('Time (min)');
            ylabel('Pixel Intensity');
            hold off
            
            % plot population 2 (high blue) red
            nexttile;
            hold on
            for i = 1:size(app.high_blue,1)
                if num_dist == 1 || num_dist == 2 % only plot if all high blue or two populations
                    plot(app.time,smoothdata(app.red_data(:,app.high_blue(i)),'movmean', app.SmoothWindow),'Linewidth',0.5,'Color',[0.9 0 0]);
                end
            end
            xlim([0 app.time(end,1)]);
            ylim([0 256]);
            set(gca,'FontSize',14,'FontWeight','bold');
            title('Mitochondrial Polarity for High Blue Intensity Cells');
            xlabel('Time (min)');
            ylabel('Pixel Intensity');
            hold off       
            
            % plot population 1 (low blue) 
            nexttile; % temp plot blue
            hold on
            for i = 1:size(app.low_blue,1)
                if num_dist == 0 || num_dist == 2 % only plot if all low blue or two populations
                    plot(app.time,smoothdata(app.blue_data(:,app.low_blue(i)),'movmean', app.SmoothWindow),'Linewidth',0.5,'Color',[0.2 0.5 1]);
                end
            end
            xlim([0 app.time(end,1)]);
            ylim([0 256]);
            set(gca,'FontSize',14,'FontWeight','bold');
            title('Nuclear Membrane Permeability for Low Blue Intensity Cells', 'FontSize', 14, 'FontWeight', "bold");
            xlabel('Time (min)');
            ylabel('Pixel Intensity');
            hold off
            
            % plot middle blue blue 
            nexttile; % temp plot blue
            hold on
            for i = 1:size(app.middle_blue,1)
                if num_dist == 0 || num_dist == 2 % only plot if all low blue or two populations
                    plot(app.time,smoothdata(app.blue_data(:,app.middle_blue(i)),'movmean', app.SmoothWindow),'Linewidth',0.5,'Color',[0.2 0.5 1]);
                end
            end
            xlim([0 app.time(end,1)]);
            ylim([0 256]);
            set(gca,'FontSize',14,'FontWeight','bold');
            title('Nuclear Membrane Permeability for Medium Blue Intensity Cells', 'FontSize', 14, 'FontWeight', "bold");
            xlabel('Time (min)');
            ylabel('Pixel Intensity');
            hold off
            
            % plot population 2 (high blue)
            nexttile;
            hold on
            for i = 1:size(app.high_blue,1)
                if num_dist == 1 || num_dist == 2 % only plot if all low blue or two populations
                    plot(app.time,smoothdata(app.blue_data(:,app.high_blue(i)),'movmean', app.SmoothWindow),'Linewidth',0.5,'Color',[0.2 0.5 1]);
                end
            end
            xlim([0 app.time(end,1)]);
            ylim([0 256]);
            set(gca,'FontSize',14,'FontWeight','bold');
            title('Nuclear Membrane Permeability for High Blue Intensity Cells', 'FontSize', 14, 'FontWeight', "bold");
            xlabel('Time (min)');
            ylabel('Pixel Intensity');
            hold off

            
        end

        % Button pushed function: HighlightCellButton
        function HighlightCellButtonPushed(app, event)
            % not modified
            
            if app.ismiddle == 1
                middle_list = app.CellList(app.middle_blue,:);
                HighlightCell(app, app.green_data(:,app.middle_blue(:)), app.red_data(:,app.middle_blue(:)), app.blue_data(:,app.middle_blue(:)), middle_list);
            else
                HighlightCell(app, app.green_data, app.red_data, app.blue_data, app.CellList);
            end
        end

        % Button pushed function: HistogramTimeseriesButton
        function HistogramTimeseriesButtonPushed(app, event)
            % need to do
            
            
            hist_popout = figure('Name','Histograms','Position',[50 200 2000 500]);
            
            skip_parameter = 10;
            
            for i = 1:app.total_imgs/10
                tiledlayout(hist_popout,1,3);
                nexttile;
                [hg1, hg2] = hist(app.green_data(i*skip_parameter,:));
                hg = bar(hg2, hg1, 'hist');
                set(hg, 'FaceColor', 'g', 'EdgeColor', 'black');
                xlim([0 256]);
                title('Calcium Intensity','FontSize',14);
                xlabel('Intensity');
                ylabel('Count');
            
                nexttile;
                [hr1, hr2] = hist(app.red_data(i*skip_parameter,:));
                hr = bar(hr2, hr1, 'hist');
                set(hr, 'FaceColor', 'r', 'EdgeColor', 'black');
                xlim([0 256]);
                title('Polarized Mitochondria','FontSize',14);
                xlabel('Intensity');
                ylabel('Count');
            
                nexttile;
                [hb1, hb2] = hist(app.blue_data(i*skip_parameter,:));
                hb = bar(hb2, hb1, 'hist');
                set(hb, 'FaceColor', 'b', 'EdgeColor', 'black');
                xlim([0 256]);
                title('Nuclear Membrane Permeability','FontSize',14);
                xlabel('Intensity');
                ylabel('Count');
                pause(0.01);
            end
        end

        % Button pushed function: CloseAllButton
        function CloseAllButtonPushed(app, event)
            close all;
        end

        % Button pushed function: CellPopulationsButton
        function CellPopulationsButtonPushed(app, event)
            % not modified
            
            ClearAxes(app);
            app.ismiddle = 1;
            % plot red
            axis(app.RedAxes,'on')
            hold(app.RedAxes,"on")
            app.RedAxes.Title.String = 'Mitochondrial Polarity for Medium Blue Intensity Cells';
            app.RedAxes.XLim = [0 app.total_imgs*app.postimpact_mpf];
            app.RedAxes.XLabel.String = 'Time (min)';
            for i = 1:size(app.middle_blue,1)
                plot(app.RedAxes,app.postimpact_time,smoothdata(app.red_data(:,app.middle_blue(i)),'movmean',app.SmoothWindow),'Linewidth',0.5,'Color',[1 0.529 0.529]);
            end
            legend(app.RedAxes,"hide");
            hold(app.RedAxes,"off")
            % plot green
            axis(app.GreenAxes,'on')
            hold(app.GreenAxes,"on")
            app.GreenAxes.Title.String = 'Calcium Concentration for Medium Blue Intensity Cells';
            app.GreenAxes.XLim = [0 app.total_imgs*app.postimpact_mpf];
            app.GreenAxes.XLabel.String = 'Time (min)';
            for i = 1:size(app.middle_blue,1)
                plot(app.GreenAxes,app.postimpact_time,smoothdata(app.green_data(:,app.middle_blue(i)),'movmean',app.SmoothWindow),'Linewidth',0.5,'Color',[0.529 1 0.5529]);
            end
            legend(app.GreenAxes,"hide");
            hold(app.GreenAxes,"off")
            % plot blue
            axis(app.BlueAxes,'on')
            hold(app.BlueAxes,"on")
            app.BlueAxes.Title.String = 'Nuclear Membrane Permeability for Medium Blue Intensity Cells';
            app.BlueAxes.XLim = [0 app.total_imgs*app.postimpact_mpf];
            app.BlueAxes.XLabel.String = 'Time (min)';
            for i = 1:size(app.middle_blue,1)
                plot(app.BlueAxes,app.postimpact_time,smoothdata(app.blue_data(:,app.middle_blue(i)),'movmean',app.SmoothWindow),'Linewidth',0.5,'Color',[0.529 0.756 1]);
            end
            legend(app.BlueAxes,"hide");
            hold(app.BlueAxes,"off")
        end

        % Button down function: GreenAxes
        function GreenAxesButtonDown(app, event)
            % check to make sure it works
            app.currentaxis = app.GreenAxes; % not sure if currentaxis = UIAxes2 is going to work
            %disp(2);
            app.UIFigure.HandleVisibility = 'callback';
            [app.pointx, app.pointy] = ginput(1);
            app.UIFigure.HandleVisibility = 'off';            
        end

        % Button down function: RedAxes
        function RedAxesButtonDown(app, event)
            % check to make sure it works
            app.currentaxis = app.RedAxes;
            %disp(2);
            app.UIFigure.HandleVisibility = 'callback';
            [app.pointx, app.pointy] = ginput(1);
            app.UIFigure.HandleVisibility = 'off';  
        end

        % Button down function: BlueAxes
        function BlueAxesButtonDown(app, event)
            % check to make sure it works
            app.currentaxis = app.BlueAxes;
            %disp(2);
            app.UIFigure.HandleVisibility = 'callback';
            [app.pointx, app.pointy] = ginput(1);
            app.UIFigure.HandleVisibility = 'off';  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ImageAxes = uiaxes(app.LeftPanel);
            app.ImageAxes.XLim = [0 512];
            app.ImageAxes.YLim = [0 512];
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            app.FrameLabel = uilabel(app.LeftPanel);
            app.FrameLabel.Text = 'Frame #';
            app.SaveCellIDListButton = uibutton(app.LeftPanel, 'push');
            app.SaveCellIDListButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCellIDListButtonPushed, true);
            app.SaveCellIDListButton.Text = 'Save Cell ID List';
            app.VideoControlsPanel = uipanel(app.LeftPanel);
            app.VideoControlsPanel.Title = 'Video Controls';
            app.PlayPauseButton = uibutton(app.VideoControlsPanel, 'state');
            app.PlayPauseButton.ValueChangedFcn = createCallbackFcn(app, @PlayPauseButtonValueChanged, true);
            app.PlayPauseButton.Text = 'Play/Pause';
            app.SelectvideoButton = uibutton(app.VideoControlsPanel, 'push');
            app.SelectvideoButton.ButtonPushedFcn = createCallbackFcn(app, @SelectvideoButtonPushed, true);
            app.SelectvideoButton.Text = 'Select video';
            app.FramerateEditFieldLabel = uilabel(app.VideoControlsPanel);
            app.FramerateEditFieldLabel.Text = 'Framerate';
            app.FramerateEditField = uieditfield(app.VideoControlsPanel, 'numeric');
            app.FramerateEditField.Limits = [1 100];
            app.FramerateEditField.ValueChangedFcn = createCallbackFcn(app, @FramerateEditFieldValueChanged, true);
            app.FramerateEditField.Value = 10;
            app.SingleCellAnalysisPanel = uipanel(app.LeftPanel);
            app.SingleCellAnalysisPanel.Title = 'Single Cell Analysis';
            app.SelectCellFromImageButton = uibutton(app.SingleCellAnalysisPanel, 'push');
            app.SelectCellFromImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCellFromImageButtonPushed, true);
            app.SelectCellFromImageButton.Text = 'Select cell in image';
            app.XCoordinateEditFieldLabel = uilabel(app.SingleCellAnalysisPanel);
            app.XCoordinateEditFieldLabel.Text = 'X Coordinate';
            app.XCoordinateEditField = uieditfield(app.SingleCellAnalysisPanel, 'numeric');
            app.YCoordinateEditFieldLabel = uilabel(app.SingleCellAnalysisPanel);
            app.YCoordinateEditFieldLabel.Text = 'Y Coordinate';
            app.YCoordinateEditField = uieditfield(app.SingleCellAnalysisPanel, 'numeric');
            app.SelectcellfromcoordinatesButton = uibutton(app.SingleCellAnalysisPanel, 'push');
            app.SelectcellfromcoordinatesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectcellfromcoordinatesButtonPushed, true);
            app.SelectcellfromcoordinatesButton.Text = {'Select cell from '; 'coordinates'};
            app.CellIDEditFieldLabel = uilabel(app.SingleCellAnalysisPanel);
            app.CellIDEditFieldLabel.Text = 'Cell ID';
            app.CellIDEditField = uieditfield(app.SingleCellAnalysisPanel, 'numeric');
            app.CellIDEditField.ValueChangedFcn = createCallbackFcn(app, @CellIDEditFieldValueChanged, true);
            app.ClosestCellxEditFieldLabel = uilabel(app.SingleCellAnalysisPanel);
            app.ClosestCellxEditFieldLabel.Text = 'Closest Cell x';
            app.ClosestCellxEditField = uieditfield(app.SingleCellAnalysisPanel, 'numeric');
            app.ClosestCellyEditFieldLabel = uilabel(app.SingleCellAnalysisPanel);
            app.ClosestCellyEditFieldLabel.Text = 'Closest Cell y';
            app.ClosestCellyEditField = uieditfield(app.SingleCellAnalysisPanel, 'numeric');
            app.FrameSlider = uislider(app.LeftPanel);
            app.FrameSlider.Limits = [0 750];
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.MultiCellAnalysisPanel = uipanel(app.LeftPanel);
            app.MultiCellAnalysisPanel.Title = 'Multi Cell Analysis';
            app.SelectAreaButton = uibutton(app.MultiCellAnalysisPanel, 'push');
            app.SelectAreaButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAreaButtonPushed, true);
            app.SelectAreaButton.Text = 'Select Area';
            app.ClearCurrentDataButton = uibutton(app.MultiCellAnalysisPanel, 'push');
            app.ClearCurrentDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearCurrentDataButtonPushed, true);
            app.ClearCurrentDataButton.Text = 'Clear Current Data';
            app.TimepointHistogramsButton = uibutton(app.MultiCellAnalysisPanel, 'push');
            app.TimepointHistogramsButton.ButtonPushedFcn = createCallbackFcn(app, @TimepointHistogramsButtonPushed, true);
            app.TimepointHistogramsButton.Text = {'Timepoint'; 'Histograms'};
            app.HighlightCellButton = uibutton(app.MultiCellAnalysisPanel, 'push');
            app.HighlightCellButton.ButtonPushedFcn = createCallbackFcn(app, @HighlightCellButtonPushed, true);
            app.HighlightCellButton.Text = 'Highlight Cell';
            app.HistogramTimeseriesButton = uibutton(app.MultiCellAnalysisPanel, 'push');
            app.HistogramTimeseriesButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramTimeseriesButtonPushed, true);
            app.HistogramTimeseriesButton.Text = 'Histogram Timeseries';
            app.CellPopulationsButton = uibutton(app.MultiCellAnalysisPanel, 'push');
            app.CellPopulationsButton.ButtonPushedFcn = createCallbackFcn(app, @CellPopulationsButtonPushed, true);
            app.CellPopulationsButton.Text = 'Cell Populations';
            app.SmoothingwindowsizeLabel = uilabel(app.LeftPanel);
            app.SmoothingwindowsizeLabel.Text = {'Smoothing'; 'window size'};
            app.SmoothingwindowsizeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SmoothingwindowsizeEditField.Limits = [0 100];
            app.SmoothingwindowsizeEditField.ValueChangedFcn = createCallbackFcn(app, @SmoothingwindowsizeEditFieldValueChanged, true);
            app.SmoothingwindowsizeEditField.Value = 20;
            app.CloseAllButton = uibutton(app.LeftPanel, 'push');
            app.CloseAllButton.ButtonPushedFcn = createCallbackFcn(app, @CloseAllButtonPushed, true);
            app.CloseAllButton.Text = 'Close All';
            app.RightPanel = uipanel(app.GridLayout);
            app.GreenAxes = uiaxes(app.RightPanel);
            xlabel(app.GreenAxes, 'Frame')
            ylabel(app.GreenAxes, 'Pixel Intensity')
            app.GreenAxes.XLim = [0 750];
            app.GreenAxes.YLim = [0 256];
            app.GreenAxes.ButtonDownFcn = createCallbackFcn(app, @GreenAxesButtonDown, true);
            app.RedAxes = uiaxes(app.RightPanel);
            xlabel(app.RedAxes, {'Frame'; ''})
            ylabel(app.RedAxes, 'Pixel Intensity')
            app.RedAxes.XLim = [0 750];
            app.RedAxes.YLim = [0 256];
            app.RedAxes.ButtonDownFcn = createCallbackFcn(app, @RedAxesButtonDown, true);
            app.BlueAxes = uiaxes(app.RightPanel);
            xlabel(app.BlueAxes, 'Frame')
            ylabel(app.BlueAxes, 'Pixel Intensity')
            app.BlueAxes.XLim = [0 750];
            app.BlueAxes.YLim = [0 256];
            app.BlueAxes.ButtonDownFcn = createCallbackFcn(app, @BlueAxesButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = STRAINS_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end